classdef GUIDECV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        text1        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
        pushbutton5  matlab.ui.control.Button
        pushbutton8  matlab.ui.control.Button
        pushbutton7  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        axes3        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit1_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function slider1_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider2_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider3_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool3, app.uitoggletool4, app.uitoggletool5, app.uitoggletool6];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUIDECV_OpeningFcn(app, varargin)
            % --- Executes just before GUIDECV is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUIDECV (see VARARGIN)
            
            % Choose default command line output for GUIDECV
            handles.output = hObject;
            % global pos1
            % global pos2
            % global pos3
            % global pos4
            % global max_y
            % global max_x
            % global min_y
            % global min_x
            % global x_max
            % global y_max
            % global vanishing_point
            % global m
            % global n
            % global p
            % global min_y_fore
            % global max_y_fore
            % global min_x_fore
            % global max_x_fore
            % global poss1
            % global poss2
            % global poss3
            % global poss4
            % global estimatedVertex
            %  Set toolbar property of the UIFigure
            % app.figure1.ToolBar = 'figure'; % or 'auto' depending on your preference
            % cameratoolbar('NoReset')%Show camera toolbar without presets
            % global img
            % global test
            % global FGVertex
            % global outFG
            % global foreobj
            % global pic_num
            % global foreground
            % global background
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            axes(handles.axes3);
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'select a picture');
            str=[pathname filename];
            % Check if the file is empty
            global img
            img = imread(str);
            % imshow(im)
            if isequal(filename,0)||isequal(pathname,0)
                warndlg('please select a picture first!','warning');
                return;
            else
                img = imread(str);
                imshow(img);
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close all;
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(handles.axes3); %Specify the coordinates that need to be cleared
            cla reset;
            set(handles.axes3,'visible','off');
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % --- Executes on button press in pushbutton4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.axes3 = gca;%Get the current axis
            h=drawrectangle("Label",'Innerrectangle','Color','r');%The mouse turns into a cross to select the region of interest
            
            
            %There will be a rectangular box that can be dragged and resized
            
            pos=h.Position;%pos has four values，are the coordinates of the upper left corner of the rectangular box x y and the width and height of the box
            hold on
            global pos1
            global pos2
            global pos3
            global pos4
            pos1=[pos(1),pos(2)]
            %left up, point7
            pos2=[pos(1)+pos(3),pos(2)]
            %right up, point8
            pos3=[pos(1)+pos(3),pos(2)+pos(4)]
            %right down, point2
            pos4=[pos(1),pos(2)+pos(4)]
            %left down, point1
            global max_y
            global max_x
            global min_y
            global min_x
             min_y=pos(2);
             max_y=pos(2)+pos(4);
             min_x=pos(1);
             max_x=pos(1)+pos(3);
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % --- Executes on button press in pushbutton5.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [x,y] = ginput(1);
            
            hold on
            a=min(x);
            b=min(y);%Get the pixel coordinates of a point clicked by the mouse, x saves the abscissa vector, y saves the ordinate vector
            a=floor(a); %x coordinate of vanishing point
            b=floor(b); %y coordinate of vanishing point
            T=[a,b]; %[x y] coordinate of vanishing point
            global vanishing_point
            global estimatedVertex
            vanishing_point=[b,a];
            % plot(a,b,'bo')
            global pos1
            global pos2
            global pos3
            global pos4
            
            global max_y
            global max_x
            global min_y
            global min_x
            
            global img
            global x_max
            global y_max
            global m
            global n
            global p
            global pic_num
            global foreground
             [m,n,p]=size(img);
                x_max = n;
                y_max = m;
            
               x_vp = a;
               y_vp = b;
            
            coordinat_sp(1,:)= pos4; %1
            coordinat_sp(2,:) = pos3; %2
            coordinat_sp(3,:) = pos2; %8
            coordinat_sp(4,:) = pos1; %7
            
            
            % gradient for the foreground
            for i =1:4
                gradient(i) = (coordinat_sp(i,2)-y_vp)/(coordinat_sp(i,1)-x_vp);
            end
            %plot(x_sp,y_sp,'b')
            estimatedVertex = zeros(2,12);
            estimatedVertex(:,1) = pos4;
            estimatedVertex(:,2) = pos3;
            estimatedVertex(:,8) = pos2;
            estimatedVertex(:,7) = pos1;
            
            estimatedVertex(:,3) = [(y_max-y_vp)/gradient(1) + x_vp; y_max];
            estimatedVertex(:,4) = [(y_max-y_vp)/gradient(2) + x_vp; y_max];
            estimatedVertex(:,5) = [1; (1-x_vp)*gradient(1) + y_vp];
            estimatedVertex(:,6) = [x_max; (x_max-x_vp)*gradient(2) + y_vp];
            estimatedVertex(:,9) = [(1-y_vp)/gradient(4) + x_vp; 1];
            estimatedVertex(:,10) = [(1-y_vp)/gradient(3) + x_vp; 1];
            estimatedVertex(:,12) = [x_max; (x_max-x_vp)*gradient(3) + y_vp];
            estimatedVertex(:,11) = [1; (1-x_vp)*gradient(4) + y_vp];
            
            
            for i = 1:12
                plot([estimatedVertex(1,i),x_vp],[estimatedVertex(2,i),y_vp],'r-','lineWidth',2)
                text(estimatedVertex(1,i),estimatedVertex(2,i),num2str(i))
            end
            
             plot([estimatedVertex(1,5),estimatedVertex(1,11)],[estimatedVertex(2,5),estimatedVertex(2,11)],'r-','lineWidth',3)
             plot([estimatedVertex(1,9),estimatedVertex(1,10)],[estimatedVertex(2,9),estimatedVertex(2,10)],'r-','lineWidth',3)
             plot([estimatedVertex(1,6),estimatedVertex(1,12)],[estimatedVertex(2,6),estimatedVertex(2,12)],'r-','lineWidth',3)
             plot([estimatedVertex(1,3),estimatedVertex(1,4)],[estimatedVertex(2,3),estimatedVertex(2,4)],'r-','lineWidth',3)
            
             pic_num=ceil(max(size(img(:,:)))/5);
             foreground = {};
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % --- Executes on button press in pushbutton7.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global img
            global x_max
            global y_max
            global max_y
            global max_x
            global min_y
            global min_x
            global m
            global n
            global p
            global vanishing_point
            global FGVertex
            global outFG
            global foreobj
            global test
            global foreground
            global background
            global pic_num
            hold off
            x_max=floor(x_max);
            y_max=floor(y_max);
            max_y=floor(max_y);
            max_x=floor(max_x);
            min_y=floor(min_y);
            min_x=floor(min_x);
            vanishing_point=floor(vanishing_point);
            box=[min_y,max_y,min_x,max_x]; % box of inner rectangle
            mx=0;my=0;mz=0;rx=0;ry=0;rz=0;
            geo=[mx,my,mz,rx,ry,rz];
            
             [out,test]= TITP(img,vanishing_point,box);
             f = figure;
             reconstruction_5surface(out)
             hold on
             if numel(foreground) ~= 0
                   [X,Y,Z,C,outputAlpha] = reconstruction_FGsurface(foreground,out,FGVertex,foreobj,test,pic_num);
                    h = surface(X,Y,Z,C);
                    set(h,'LineStyle','none');
                    h.AlphaData = outputAlpha;
                    h.FaceAlpha = 'flat';
             end
              cameratoolbar('NoReset')
             tb = cameratoolbar(f);
             view(0,-90)
             setCamera(out)
             axis off
        end

        % Button pushed function: pushbutton8
        function pushbutton8_Callback(app, event)
            % --- Executes on button press in pushbutton8.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            h=drawrectangle('Label','foreobj','Color','y');
            global img
            poss=h.Position;
            hold on
            global poss1
            global poss2
            global poss3
            global poss4
            poss1=[poss(1),poss(2)]
            %left up
            poss2=[poss(1)+poss(3),poss(2)]
            %right up
            poss3=[poss(1)+poss(3),poss(2)+poss(4)]
            %right down
            poss4=[poss(1),poss(2)+poss(4)]
            %left down
            global vanishing_point
            global min_y_fore
            global max_y_fore
            global min_x_fore
            global max_x_fore
            global estimatedVertex
            global FGVertex
            global outFG
            global foreobj
            global test
            global foreground
            global background
            min_y_fore=poss(2);
            max_y_fore=poss(2)+poss(4);
            min_x_fore=poss(1);
            max_x_fore=poss(1)+poss(3);
            [foreground, img] = maskBackground(img, min_y_fore,max_y_fore,min_x_fore,max_x_fore);
            [FGVertex,foreobj]  = getforegroundobj(foreground,vanishing_point,estimatedVertex,min_y_fore,max_y_fore,min_x_fore,max_x_fore);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUIDECV';
            app.figure1.Tag = 'figure1';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Quit';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Reset';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'Start';
            app.pushbutton8 = uibutton(app.figure1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = 'Foreground';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'Vanishing point';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Inner rectangle';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Select Image';
            app.text3 = uilabel(app.figure1);
            app.text3.Text = {'Step5: Click "Start". Wait and see the 3d construction space!   '; '                                             '; 'Step6: Click "Reset". You can select a new image and repeat step 1-5                                '; '                                                               '; 'Step7: Click "Quit". You will close the program! Thanks for using!'};
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text4';
            app.text2.Text = {'Step1: Click “Select Image”. You can choose the picture you are interested in.'; ''; 'Step2: Click "Inner rectangle".  This is the ''rear wall'' in the 3D space.'; ''; 'Step3: Click "Vanishing point". You can select a vanishing point within the inner rectangle.'; ''; 'Step4: Click "Foreground". You can select a foreground object model.( You can also skip this step without selecting the foreground object.)'; ''; ''};
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text2';
            app.text1.Text = {'  Tour into the picture'; 'TUM CV challenge SS24 '};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIDECV_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUIDECV_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end