classdef GUIDECV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        pushbutton2  matlab.ui.control.Button
        pushbutton7  matlab.ui.control.Button
        pushbutton3  matlab.ui.control.Button
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        pushbutton8  matlab.ui.control.Button
        pushbutton5  matlab.ui.control.Button
        pushbutton4  matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        text1        matlab.ui.control.Label
        axes3        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit1_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function slider1_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider2_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function slider3_CreateFcn(~, hObject, ~, ~)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool3, app.uitoggletool4, app.uitoggletool5, app.uitoggletool6];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUIDECV_OpeningFcn(app, varargin)
            % --- Executes just before GUIDECV is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            

            handles.output = hObject;

            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            global img
            [filename,pathname]=uigetfile({'*.bmp;*.jpg;*.png;*.jpeg;*.tif'},'select a picture');
            str=[pathname filename];
            img = imread(str);
            axes(handles.axes3);
            % imshow(im)
            if isequal(filename,0)||isequal(pathname,0)
                warndlg('please select a picture first!','warning');
                return;
            else
                img = imread(str);
                imshow(img);
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % --- Executes on button press in pushbutton2.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close all;
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            

            axes(handles.axes3); %Specify the coordinates that need to be cleared
            cla reset;
            set(handles.axes3,'visible','off');
        end

        % Button pushed function: pushbutton4
        function pushbutton4_Callback(app, event)
            % --- Executes on button press in pushbutton4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            app.axes3 = gca;%Get the current axis
            h=drawrectangle("Label",'Innerrectangle','Color','r');%The mouse turns into a cross to select the region of interest
            hold off
            
            %There will be a rectangular box that can be dragged and resized
            
            pos=h.Position;%pos has four values，are the coordinates of the upper left corner of the rectangular box x y and the width and height of the box

            global pos1
            global pos2
            global pos3
            global pos4
            pos1=[pos(1),pos(2)];
            %left up, point7

            pos2=[pos(1)+pos(3),pos(2)];
            %right up, point8

            pos3=[pos(1)+pos(3),pos(2)+pos(4)];
            %right down, point2

            pos4=[pos(1),pos(2)+pos(4)];
            %left down, point1

            global max_y
            global max_x
            global min_y
            global min_x

            min_y=pos(2);
            max_y=pos(2)+pos(4);
            min_x=pos(1);
            max_x=pos(1)+pos(3);
        end

        % Button pushed function: pushbutton5
        function pushbutton5_Callback(app, event)
            % --- Executes on button press in pushbutton5.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
                set(gcf, 'Pointer', 'crosshair'); % Set pointer to crosshair for ginput
                drawnow;
                [x,y] = ginput(1);
                hold(app.axes3, "on")
                % Restore pointer after ginput
                set(gcf, 'Pointer', 'arrow');
                drawnow;
            catch ME
                 % Restore pointer in case of error
                set(gcf, 'Pointer', 'arrow');
                drawnow; % Force the update to apply the pointer change immediately
                rethrow(ME);
            end
            
            x=floor(x); %x coordinate of vanishing point
            y=floor(y); %y coordinate of vanishing point

            global vanishing_point      
            vanishing_point=[y,x];                                                        
            global max_y
            global max_x
            global min_y
            global min_x
            
            global img
            global pic_num
            global foreground
            global estimatedVertex

            box=[min_y,max_y,min_x,max_x]; % box of inner rectangle
            estimatedVertex = get12vertex(img,vanishing_point,box);
            x_vp=vanishing_point(2);
            y_vp=vanishing_point(1);

            % Plot results in the original GUI figure (axes3)
            
            plot(app.axes3, x_vp, y_vp, 'rx', 'LineWidth', 2, 'MarkerSize', 10); % Mark the vanishing point

            for i = 1:12
                plot(app.axes3, [estimatedVertex(1, i), x_vp], [estimatedVertex(2, i), y_vp], 'g-', 'LineWidth', 2);
                text(app.axes3, estimatedVertex(1, i), estimatedVertex(2, i), num2str(i), 'Color', 'w');
            end
    
            plot(app.axes3, [estimatedVertex(1, 5), estimatedVertex(1, 11)], [estimatedVertex(2, 5), estimatedVertex(2, 11)], 'r-', 'LineWidth', 2);
            plot(app.axes3, [estimatedVertex(1, 9), estimatedVertex(1, 10)], [estimatedVertex(2, 9), estimatedVertex(2, 10)], 'r-', 'LineWidth', 2);
            plot(app.axes3, [estimatedVertex(1, 6), estimatedVertex(1, 12)], [estimatedVertex(2, 6), estimatedVertex(2, 12)], 'r-', 'LineWidth', 2);
            plot(app.axes3, [estimatedVertex(1, 3), estimatedVertex(1, 4)], [estimatedVertex(2, 3), estimatedVertex(2, 4)], 'r-', 'LineWidth', 2);
            
            set(gcf, 'Pointer', 'arrow');
            drawnow;
            pic_num=ceil(max(size(img(:,:)))/5);
            foreground = {};

        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % --- Executes on button press in pushbutton7.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            

            global img
            global x_max
            global y_max
            global max_y
            global max_x
            global min_y
            global min_x
            global vanishing_point
            global FGVertex
            global foreobj
            global test
            global foreground
            global pic_num
            hold off
            x_max=floor(x_max);
            y_max=floor(y_max);
            max_y=floor(max_y);
            max_x=floor(max_x);
            min_y=floor(min_y);
            min_x=floor(min_x);
            vanishing_point=floor(vanishing_point);
            box=[min_y,max_y,min_x,max_x]; % box of inner rectangle
            mx=0;my=0;mz=0;rx=0;ry=0;rz=0;
            geo=[mx,my,mz,rx,ry,rz];
            
             [surfaces,test]= TITP(img,vanishing_point,box);
             f = figure;
             reconstruction_5surface(surfaces)
             hold on
             if numel(foreground) ~= 0
                   [X,Y,Z,C,outputAlpha] = reconstruction_FGsurface(foreground,surfaces,FGVertex,foreobj,test,pic_num);
                   h = surface(X,Y,Z,C);
                   set(h,'LineStyle','none');
                   h.AlphaData = outputAlpha;
                   h.FaceAlpha = 'flat';
             end
             cameratoolbar('NoReset')
             tb = cameratoolbar(f);
             view(0,-90)
             setCamera(surfaces)
             axis off
        end

        % Button pushed function: pushbutton8
        function pushbutton8_Callback(app, event)
            % --- Executes on button press in pushbutton8.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            global img
            % hObject    handle to pushbutton8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            roi = drawassisted;
            
            poss=roi.Position;
            hold on
            %{
            global poss1
            global poss2
            global poss3
            global poss4
            poss1=[min(poss(1,:)),min(poss(2,:))];
            %left up
            poss2=[max(poss(1,:)),min(poss(2,:))];
            %right up
            poss3=[max(poss(1,:)),max(poss(2,:))];
            %right down
            poss4=[min(poss(1,:)),max(poss(2,:))];
            %left down
            %}
            global vanishing_point
            global min_y_fore
            global max_y_fore
            global min_x_fore
            global max_x_fore
            global estimatedVertex
            global FGVertex
            global foreobj
            global foreground
            min_y_fore=min(poss(:,2));
            max_y_fore=max(poss(:,2));
            min_x_fore=min(poss(:,1));
            max_x_fore=max(poss(:,1));
            [foreground, img] = maskBackground(img, poss);
            [FGVertex,foreobj]  = getforegroundobj(foreground,vanishing_point,estimatedVertex,min_y_fore,max_y_fore,min_x_fore,max_x_fore);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUIDECV';
            app.figure1.Tag = 'figure1';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text2';
            app.text1.Text = {'  Tour into the picture'; 'TUM CV challenge SS24 '};
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Select Image';
            app.pushbutton4 = uibutton(app.figure1, 'push');
            app.pushbutton4.ButtonPushedFcn = createCallbackFcn(app, @pushbutton4_Callback, true);
            app.pushbutton4.Tag = 'pushbutton4';
            app.pushbutton4.Text = 'Inner rectangle';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5_Callback, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = 'Vanishing point';
            app.pushbutton8 = uibutton(app.figure1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8_Callback, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = 'Foreground';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text4';
            app.text2.Text = {'Step1: Click “Select Image”. You can choose the picture you are interested in.'; ''; 'Step2: Click "Inner rectangle".  This is the ''rear wall'' in the 3D space.'; ''; 'Step3: Click "Vanishing point". You can select a vanishing point within the inner rectangle.'; ''; 'Step4: Click "Foreground". You can select a foreground object model.( You can also skip this step without selecting the foreground object.)'; ''; ''};
            app.text3 = uilabel(app.figure1);
            app.text3.Text = {'Step5: Click "Start". Wait and see the 3d construction space!   '; '                                             '; 'Step6: Click "Reset". You can select a new image and repeat step 1-5                                '; '                                                               '; 'Step7: Click "Quit". You will close the program! Thanks for using!'};
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Reset';
            app.pushbutton7 = uibutton(app.figure1, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'Start';
            app.pushbutton2 = uibutton(app.figure1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'Quit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIDECV_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUIDECV_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end